classdef App_Ent_Backpropagation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EntrenamientoTab                matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        PrecisinEditField               matlab.ui.control.EditField
        PrecisinEditFieldLabel          matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.EditField
        BetaEditFieldLabel              matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.EditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        ArquitecturaEditField           matlab.ui.control.EditField
        ArquitecturaEditFieldLabel      matlab.ui.control.Label
        ErrorvspocasLabel               matlab.ui.control.Label
        PesosvspocasLabel               matlab.ui.control.Label
        ArquitecturaLabel               matlab.ui.control.Label
        EntrenarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_3      matlab.ui.control.Button
        PesosTextArea                   matlab.ui.control.TextArea
        PesosTextAreaLabel              matlab.ui.control.Label
        SeleccionararchivoButton_2      matlab.ui.control.Button
        SalidadeseadaTextArea           matlab.ui.control.TextArea
        SalidadeseadaTextAreaLabel      matlab.ui.control.Label
        SeleccionararchivoButton        matlab.ui.control.Button
        EntradasTextArea                matlab.ui.control.TextArea
        EntradasTextAreaLabel           matlab.ui.control.Label
        CargadearchivosporfavorseleccioneunarchivoLabel  matlab.ui.control.Label
        pocasTextArea                   matlab.ui.control.TextArea
        pocasTextAreaLabel              matlab.ui.control.Label
        ErrorFinalTextArea              matlab.ui.control.TextArea
        ErrorFinalTextAreaLabel         matlab.ui.control.Label
        DatosdeentrenamientoLabel       matlab.ui.control.Label
        UniversidaddeCundinamarcaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EntrenamientoLaboratorio3InteligenciaArtificialLabel  matlab.ui.control.Label
        VerArquitectura                 matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        AplicacinTab                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ImagencargadaLabel              matlab.ui.control.Label
        EjecutarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_5      matlab.ui.control.Button
        SeleccionararchivoButton_4      matlab.ui.control.Button
        PesosTextArea_2                 matlab.ui.control.TextArea
        PesosTextArea_2Label            matlab.ui.control.Label
        EntradasTextArea_2              matlab.ui.control.TextArea
        EntradasTextArea_2Label         matlab.ui.control.Label
        UniversidaddeCundinamarcaLabel_2  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        AplicacinLaboratorio3InteligenciaArtificialLabel  matlab.ui.control.Label
        ImagenCargada                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    methods (Access = private)
        
        function verArquitectura(app, arquitectura)          
            
            % Limpiar el eje y preparar el gráfico
            cla(app.VerArquitectura);
            hold(app.VerArquitectura, 'on');

            % Configuración inicial
            max_neuronas = max(arquitectura);
            colores = {'#8F3985', '#EC4E20', '#7FD1B9', '#7E2F8E', '#77AC30'};

            % Dibujar cada capa
            for i = 1:length(arquitectura)
                x = i;

                % Dibujar neuronas
                for j = 1:arquitectura(i)
                    y = (max_neuronas + 1 - arquitectura(i))/2 + j;
                    color_idx = mod(i-1, length(colores)) + 1;

                    % Usar plot en UIAxes
                    plot(app.VerArquitectura, x, y, 'o', ...
                        'MarkerSize', 10, ...
                        'MarkerFaceColor', colores{color_idx}, ...
                        'MarkerEdgeColor', 'k');

                    % Dibujar conexiones (si no es la capa de entrada)
                    if i > 1
                        for k = 1:arquitectura(i-1)
                            y_prev = (max_neuronas + 1 - arquitectura(i-1))/2 + k;
                            plot(app.VerArquitectura, [x-1, x], [y_prev, y], ...
                                'k-', 'LineWidth', 0.5);
                        end
                    end
                end

                % Etiquetas de capa
                if i == 1
                    text(app.VerArquitectura, x, 0.5, 'Entrada', ...
                        'HorizontalAlignment', 'center');
                elseif i == length(arquitectura)
                    text(app.VerArquitectura, x, 0.5, 'Salida', ...
                        'HorizontalAlignment', 'center');
                else
                    text(app.VerArquitectura, x, 0.5, ['Capa ', num2str(i-1)], ...
                        'HorizontalAlignment', 'center');
                end
            end

            % Configuración final del eje
            xlim(app.VerArquitectura, [0.5, length(arquitectura) + 0.5]);
            ylim(app.VerArquitectura, [0, max_neuronas + 1]);
            axis(app.VerArquitectura, 'off');
            hold(app.VerArquitectura, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            verArquitectura(app,str2num(app.ArquitecturaEditField.Value));

             try
                nombreImagenDefecto = 'VOCALES_IMAGE.png';
        
                % 2. Obtener la ruta del directorio donde se está ejecutando la app
                appDirectorio = fileparts(mfilename('fullpath'));
                % 3. Construir la ruta completa al archivo de imagen
                rutaCompletaImagenDefecto = fullfile(appDirectorio, nombreImagenDefecto);
                % 4. Verificar si el archivo de imagen existe en esa ruta
                if exist(rutaCompletaImagenDefecto, 'file')
        
                    % 5. Leer la imagen por defecto
                    imagenDataDefecto = imread(rutaCompletaImagenDefecto);
        
                    % 6. Mostrar la imagen
                    
                    if isprop(app, 'ImagenCargada')
                        imshow(imagenDataDefecto, 'Parent', app.ImagenCargada);
        
                        % (Opcional) Puedes ajustar la apariencia de los ejes para la imagen
                        axis(app.ImagenCargada, 'image'); % Mantiene la relación de aspecto correcta
                        axis(app.ImagenCargada, 'off');   % Oculta los ejes (números, marcas)
                        title(app.ImagenCargada, '');     % Limpia cualquier título
        
                        disp('Imagen por defecto cargada correctamente.');
                    else
                        warning('No se encontró el componente UIAxes llamado "ImagenCargada" para mostrar la imagen por defecto.');
                    end
        
                else
                    % El archivo de imagen por defecto no se encontró donde se esperaba
                    warning('No se encontró el archivo de imagen por defecto: %s', rutaCompletaImagenDefecto);
                     % (Opcional) Mostrar un mensaje o dejar los ejes vacíos
                     if isprop(app, 'ImagenCargada')
                         cla(app.ImagenCargada, 'reset');
                         title(app.ImagenCargada, 'Imagen por defecto no encontrada');
                         disableDefaultInteractivity(app.ImagenCargada); % Quita zoom/pan
                     end
                end
        
             catch E
                    % Capturar cualquier error durante la carga o visualización
                    warning('Error al cargar o mostrar la imagen por defecto: %s', E.message);
                    % (Opcional) Mostrar un mensaje de error en los ejes
                     if isprop(app, 'ImagenCargada')
                         cla(app.ImagenCargada, 'reset');
                         title(app.ImagenCargada, 'Error al cargar imagen');
                         disableDefaultInteractivity(app.ImagenCargada);
                     end
             end


        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
        filter = { ...
                    '*.png;*.jpg;*.jpeg', 'Archivos de Imagen (*.png, *.jpg, *.jpeg)'; ...
                    '*.png', 'Imagen PNG (*.png)'; ...
                    '*.jpg;*.jpeg', 'Imagen JPEG (*.jpg, *.jpeg)'; ...
                    '*.txt', 'Archivos de Texto (*.txt)'; ... 
                    '*.*', 'Todos los archivos (*.*)' ...
                     };
               
        dialogTitle='Selecciona un archivo';
        [fileName, pathName] = uigetfile(filter, dialogTitle);
        % Verifica si el usuario canceló la selección
        if isequal(fileName, 0) || isequal(pathName, 0)
           disp('Selección cancelada por el usuario.');
            if isprop(app, 'EntradasTextArea') 
                app.EntradasTextArea.Value = 'Ningún archivo seleccionado.';
            end
        else
        % Construye la ruta completa del archivo
        fullPath = fullfile(pathName, fileName);
        disp(['Archivo seleccionado: ', fullPath]);
        end

        if isprop(app, 'EntradasTextArea')
           app.EntradasTextArea.Value = {fullPath};
        end
        
        end

        % Button pushed function: SeleccionararchivoButton_2
        function SeleccionararchivoButton_2Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto(*.txt)'};
        dialogTitle='Selecciona un archivo';
        [fileName, pathName] = uigetfile(filter, dialogTitle);
        % Verifica si el usuario canceló la selección
        if isequal(fileName, 0) || isequal(pathName, 0)
           disp('Selección cancelada por el usuario.');
            if isprop(app, 'SalidadeseadaTextArea') 
                app.SalidadeseadaTextArea.Value = 'Ningún archivo seleccionado.';
            end
        else
        % Construye la ruta completa del archivo
        fullPath = fullfile(pathName, fileName);
        disp(['Archivo seleccionado: ', fullPath]);
        end

        if isprop(app, 'SalidadeseadaTextArea')
           app.SalidadeseadaTextArea.Value = {fullPath};
        end
        end

        % Button pushed function: SeleccionararchivoButton_3
        function SeleccionararchivoButton_3Pushed(app, event)
             filter = {'*.txt', 'Archivos de Texto(*.txt)'};
        dialogTitle='Selecciona un archivo';
        [fileName, pathName] = uigetfile(filter, dialogTitle);
        % Verifica si el usuario canceló la selección
        if isequal(fileName, 0) || isequal(pathName, 0)
           disp('Selección cancelada por el usuario.');
            if isprop(app, 'PesosTextArea')
                app.PesosTextArea.Value = 'Ningún archivo seleccionado.';
            end
        else
        % Construye la ruta completa del archivo
        fullPath = fullfile(pathName, fileName);
        disp(['Archivo seleccionado: ', fullPath]);
        end

        if isprop(app, 'PesosTextArea')
           app.PesosTextArea.Value = {fullPath};
        end
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            verArquitectura(app,str2num(app.ArquitecturaEditField.Value));
        end

        % Button pushed function: SeleccionararchivoButton_5
        function SeleccionararchivoButton_5Pushed(app, event)
                    
            try 
            
                % 1. Código para seleccionar archivo
                filter = { ...
                    '*.png;*.jpg;*.jpeg', 'Archivos de Imagen (*.png, *.jpg, *.jpeg)'; ...
                    '*.png', 'Imagen PNG (*.png)'; ...
                    '*.jpg;*.jpeg', 'Imagen JPEG (*.jpg, *.jpeg)'; ...
                    '*.txt', 'Archivos de Texto (*.txt)'; ... 
                    '*.*', 'Todos los archivos (*.*)' ...
                     };
                dialogTitle = 'Selecciona un archivo de imagen';
            
                [fileName, pathName] = uigetfile(filter, dialogTitle);
            
                % --- 2. Procesar selección ---
                if isequal(fileName, 0) || isequal(pathName, 0)
                   disp('Selección cancelada por el usuario.');
                   if isprop(app, 'EntradasTextArea_2')
                        app.EntradasTextArea_2.Value = {'Selección cancelada.'};
                   end
                   % (Opcional) Limpiar los ejes si se cancela
                   if isprop(app, 'magenCargada') 
                       cla(app.ImagenCargada, 'reset'); 
                       disableDefaultInteractivity(app.ImagenCargada); % Quita zoom/pan
                   end
                   return; % Salir del callback si se canceló
                else
                    % Construir ruta completa
                    fullPath = fullfile(pathName, fileName);
                    disp(['Archivo seleccionado: ', fullPath]);
                    % (Opcional) Mostrar ruta en algún TextArea
                    if isprop(app, 'EntradasTextArea_2')
                       app.EntradasTextArea_2.Value = {fullPath};
                    end
            
                    % --- 3. LEER Y MOSTRAR LA IMAGEN ---
                    % Verificar que el componente UIAxes existe
                    if ~isprop(app, 'ImagenCargada')
                         uialert(app.UIFigure, 'No se encuentra el componente UIAxes_Imagen en la app.', 'Error de Configuración');
                         return;
                    end
            
                    try
                        % Leer la imagen desde el archivo usando imread
                        imageData = imread(fullPath);
            
                        % Mostrar la imagen
                        % Usando imshow (recomendado, requiere Image Processing Toolbox)
                        imshow(imageData, 'Parent', app.ImagenCargada);
            
                        % Alternativa (si no tienes Image Processing Toolbox):
                        % image(app.ImagenCargada, imageData); % ¡¡Usa el nombre de TUS ejes!!
                        % axis(app.ImagenCargada, 'image'); % Para mantener relación de aspecto
                        % axis(app.ImagenCargada, 'off'); % Para ocultar los ejes numéricos
            
                        % (Opcional) Limpiar título y etiquetas de los ejes
                        title(app.ImagenCargada, '');
                        xlabel(app.ImagenCargada, '');
                        ylabel(app.ImagenCargada, '');
            
            
                    catch ME % Capturar error si el archivo no es una imagen válida
                        disp(['Error al leer o mostrar la imagen: ', ME.message]);
                        uialert(app.ImagenCargada, sprintf('No se pudo cargar el archivo:\n"%s"\n\nAsegúrate de que sea un archivo de imagen válido.\n\nError: %s', fileName, ME.message), 'Error al Cargar Imagen');
                        % (Opcional) Limpiar los ejes si falla la carga
                        cla(app.UIAxes_Imagen, 'reset');
                        disableDefaultInteractivity(app.ImagenCargada);
                    end
                    % --- Fin Leer y Mostrar ---
                end
            
            catch ME_Callback % Capturar cualquier error inesperado en el callback
                disp(['Error en el callback del botón: ', ME_Callback.message]);
                uialert(app.UIFigure, ['Ocurrió un error inesperado: ' ME_Callback.message], 'Error de Aplicación');
            end
        end

        % Button pushed function: SeleccionararchivoButton_4
        function SeleccionararchivoButton_4Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto(*.txt)'};
        dialogTitle='Selecciona un archivo';
        [fileName, pathName] = uigetfile(filter, dialogTitle);
        % Verifica si el usuario canceló la selección
        if isequal(fileName, 0) || isequal(pathName, 0)
           disp('Selección cancelada por el usuario.');
            if isprop(app, 'PesosTextArea_2') 
                app.PesosTextArea_2.Value = 'Ningún archivo seleccionado.';
            end
        else
        % Construye la ruta completa del archivo
        fullPath = fullfile(pathName, fileName);
        disp(['Archivo seleccionado: ', fullPath]);
        end

        if isprop(app, 'PesosTextArea_2')
           app.PesosTextArea_2.Value = {fullPath};
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EntrenamientoTab = uitab(app.TabGroup);
            app.EntrenamientoTab.Title = 'Entrenamiento';
            app.GridLayout = uigridlayout(app.EntrenamientoTab);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.VerArquitectura = uiaxes(app.GridLayout);
            title(app.VerArquitectura, 'Title')
            xlabel(app.VerArquitectura, 'X')
            ylabel(app.VerArquitectura, 'Y')
            zlabel(app.VerArquitectura, 'Z')
            app.EntrenamientoLaboratorio3InteligenciaArtificialLabel = uilabel(app.GridLayout);
            app.EntrenamientoLaboratorio3InteligenciaArtificialLabel.Text = 'Entrenamiento Laboratorio #3 Inteligencia Artificial';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel = uilabel(app.GridLayout);
            app.UniversidaddeCundinamarcaLabel.Text = 'Universidad de Cundinamarca';
            app.DatosdeentrenamientoLabel = uilabel(app.GridLayout);
            app.DatosdeentrenamientoLabel.Text = 'Datos de entrenamiento';
            app.ErrorFinalTextAreaLabel = uilabel(app.GridLayout);
            app.ErrorFinalTextAreaLabel.Text = 'Error Final';
            app.ErrorFinalTextArea = uitextarea(app.GridLayout);
            app.pocasTextAreaLabel = uilabel(app.GridLayout);
            app.pocasTextAreaLabel.Text = 'Épocas';
            app.pocasTextArea = uitextarea(app.GridLayout);
            app.CargadearchivosporfavorseleccioneunarchivoLabel = uilabel(app.GridLayout);
            app.CargadearchivosporfavorseleccioneunarchivoLabel.Text = 'Carga de archivos, por favor seleccione un archivo';
            app.EntradasTextAreaLabel = uilabel(app.GridLayout);
            app.EntradasTextAreaLabel.Text = 'Entradas';
            app.EntradasTextArea = uitextarea(app.GridLayout);
            app.SeleccionararchivoButton = uibutton(app.GridLayout, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.SalidadeseadaTextAreaLabel = uilabel(app.GridLayout);
            app.SalidadeseadaTextAreaLabel.Text = {'Salida'; 'deseada'};
            app.SalidadeseadaTextArea = uitextarea(app.GridLayout);
            app.SeleccionararchivoButton_2 = uibutton(app.GridLayout, 'push');
            app.SeleccionararchivoButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_2Pushed, true);
            app.SeleccionararchivoButton_2.Text = 'Seleccionar archivo';
            app.PesosTextAreaLabel = uilabel(app.GridLayout);
            app.PesosTextAreaLabel.Text = 'Pesos';
            app.PesosTextArea = uitextarea(app.GridLayout);
            app.SeleccionararchivoButton_3 = uibutton(app.GridLayout, 'push');
            app.SeleccionararchivoButton_3.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_3Pushed, true);
            app.SeleccionararchivoButton_3.Text = 'Seleccionar archivo';
            app.EntrenarButton = uibutton(app.GridLayout, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.ArquitecturaLabel = uilabel(app.GridLayout);
            app.ArquitecturaLabel.Text = 'Arquitectura';
            app.PesosvspocasLabel = uilabel(app.GridLayout);
            app.PesosvspocasLabel.Text = 'Pesos vs Épocas';
            app.ErrorvspocasLabel = uilabel(app.GridLayout);
            app.ErrorvspocasLabel.Text = 'Error vs Épocas';
            app.ArquitecturaEditFieldLabel = uilabel(app.GridLayout);
            app.ArquitecturaEditFieldLabel.Text = 'Arquitectura';
            app.ArquitecturaEditField = uieditfield(app.GridLayout, 'text');
            app.ArquitecturaEditField.Value = '5 4 2';
            app.AlphaEditFieldLabel = uilabel(app.GridLayout);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.GridLayout, 'text');
            app.BetaEditFieldLabel = uilabel(app.GridLayout);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.GridLayout, 'text');
            app.PrecisinEditFieldLabel = uilabel(app.GridLayout);
            app.PrecisinEditFieldLabel.Text = 'Precisión';
            app.PrecisinEditField = uieditfield(app.GridLayout, 'text');
            app.AplicacinTab = uitab(app.TabGroup);
            app.AplicacinTab.Title = 'Aplicación';
            app.GridLayout2 = uigridlayout(app.AplicacinTab);
            app.ImagenCargada = uiaxes(app.GridLayout2);
            title(app.ImagenCargada, 'Title')
            zlabel(app.ImagenCargada, 'Z')
            app.AplicacinLaboratorio3InteligenciaArtificialLabel = uilabel(app.GridLayout2);
            app.AplicacinLaboratorio3InteligenciaArtificialLabel.Text = 'Aplicación Laboratorio #3 Inteligencia Artificial';
            app.Image_2 = uiimage(app.GridLayout2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel_2 = uilabel(app.GridLayout2);
            app.UniversidaddeCundinamarcaLabel_2.Text = 'Universidad de Cundinamarca';
            app.EntradasTextArea_2Label = uilabel(app.GridLayout2);
            app.EntradasTextArea_2Label.Text = 'Entradas';
            app.EntradasTextArea_2 = uitextarea(app.GridLayout2);
            app.PesosTextArea_2Label = uilabel(app.GridLayout2);
            app.PesosTextArea_2Label.Text = 'Pesos';
            app.PesosTextArea_2 = uitextarea(app.GridLayout2);
            app.SeleccionararchivoButton_4 = uibutton(app.GridLayout2, 'push');
            app.SeleccionararchivoButton_4.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_4Pushed, true);
            app.SeleccionararchivoButton_4.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_5 = uibutton(app.GridLayout2, 'push');
            app.SeleccionararchivoButton_5.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_5Pushed, true);
            app.SeleccionararchivoButton_5.Text = 'Seleccionar archivo';
            app.EjecutarButton = uibutton(app.GridLayout2, 'push');
            app.EjecutarButton.Text = 'Ejecutar';
            app.ImagencargadaLabel = uilabel(app.GridLayout2);
            app.ImagencargadaLabel.Text = 'Imagen cargada';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Ent_Backpropagation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end