classdef App_Mariposas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TratamientoTab                  matlab.ui.container.Tab
        AddedKernelsListBox             matlab.ui.control.ListBox
        KernelsAadidosLabel             matlab.ui.control.Label
        DatosparaentrenamientoLabel     matlab.ui.control.Label
        EliminarKernelButton            matlab.ui.control.Button
        MostrarKernelListaButton        matlab.ui.control.Button
        AgregarKernelButton             matlab.ui.control.Button
        FiltrosaAplicarLabel            matlab.ui.control.Label
        FiltrosdeColorDropDown          matlab.ui.control.DropDown
        FiltrosdeColorDropDownLabel     matlab.ui.control.Label
        Kernel5x5DropDown               matlab.ui.control.DropDown
        Kernel5x5DropDownLabel          matlab.ui.control.Label
        Rutaimagenkernel                matlab.ui.control.EditField
        EntradasEditField_2Label        matlab.ui.control.Label
        Seleccionarimagenkernel         matlab.ui.control.Button
        DeshacerButton                  matlab.ui.control.Button
        Kernel3x3DropDown               matlab.ui.control.DropDown
        Kernel3x3DropDownLabel          matlab.ui.control.Label
        UniversidaddeCundinamarcaLabel_3  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        EntrenamientoLaboratorio4InteligenciaArtificialLabel_2  matlab.ui.control.Label
        UIImagenTratada                 matlab.ui.control.UIAxes
        UIImagenOriginal                matlab.ui.control.UIAxes
        EntrenamientoTab                matlab.ui.container.Tab
        ThetaEditField                  matlab.ui.control.NumericEditField
        AlphaEditField_2Label_2         matlab.ui.control.Label
        ErrorActualEditField            matlab.ui.control.NumericEditField
        ErrorActualEditFieldLabel       matlab.ui.control.Label
        EpocaActualEditField            matlab.ui.control.NumericEditField
        pocaactualEditFieldLabel        matlab.ui.control.Label
        DetenerButton                   matlab.ui.control.Button
        ErrorGeneralEditField           matlab.ui.control.NumericEditField
        PrecisionClaficicacinLabel      matlab.ui.control.Label
        SeleccionarrutaButton           matlab.ui.control.Button
        PrecisinEditField               matlab.ui.control.NumericEditField
        PrecisinEditFieldLabel          matlab.ui.control.Label
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditField_2Label           matlab.ui.control.Label
        SalidasdeseadasEditField        matlab.ui.control.EditField
        SalidasdeseadasEditFieldLabel   matlab.ui.control.Label
        EntradasEditField               matlab.ui.control.EditField
        EntradasEditFieldLabel          matlab.ui.control.Label
        ErrorFinalEditField             matlab.ui.control.NumericEditField
        ErrorFinalEditFieldLabel        matlab.ui.control.Label
        pocasEditField                  matlab.ui.control.NumericEditField
        pocasEditFieldLabel             matlab.ui.control.Label
        MensajesdeestadoLabel           matlab.ui.control.Label
        GuardarPesosenEditField         matlab.ui.control.EditField
        GuardarPesosenEditFieldLabel    matlab.ui.control.Label
        PesosInicialesEditFieldLabel    matlab.ui.control.Label
        PesosInicialesEditField         matlab.ui.control.EditField
        ArquitecturaEditField           matlab.ui.control.EditField
        ArquitecturaEditFieldLabel      matlab.ui.control.Label
        ErrorvspocasLabel               matlab.ui.control.Label
        PesosvspocasLabel               matlab.ui.control.Label
        ArquitecturaLabel               matlab.ui.control.Label
        EntrenarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_3      matlab.ui.control.Button
        SeleccionararchivoButton_2      matlab.ui.control.Button
        SeleccionararchivoButton        matlab.ui.control.Button
        CargadearchivosporfavorseleccioneunarchivoLabel  matlab.ui.control.Label
        DatosdeentrenamientoLabel       matlab.ui.control.Label
        UniversidaddeCundinamarcaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        EntrenamientoLaboratorio4InteligenciaArtificialLabel  matlab.ui.control.Label
        VerArquitectura                 matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        AplicacinTab                    matlab.ui.container.Tab
        TamaoEditField                  matlab.ui.control.EditField
        TamaoEditFieldLabel             matlab.ui.control.Label
        DimensionesEditField            matlab.ui.control.EditField
        DimensionesEditFieldLabel       matlab.ui.control.Label
        BlueButton                      matlab.ui.control.Button
        GreenButton                     matlab.ui.control.Button
        RedButton                       matlab.ui.control.Button
        RGBButton                       matlab.ui.control.Button
        BrilloButton                    matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Button3                         matlab.ui.control.ToggleButton
        Button2                         matlab.ui.control.ToggleButton
        Button                          matlab.ui.control.ToggleButton
        HistogramaRGBLabel              matlab.ui.control.Label
        TipomariposaEditField           matlab.ui.control.EditField
        TipomariposaEditFieldLabel      matlab.ui.control.Label
        PesosEditField                  matlab.ui.control.EditField
        PesosEditFieldLabel             matlab.ui.control.Label
        EntradaEditField                matlab.ui.control.EditField
        EntradaEditFieldLabel           matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        ImagencargadaLabel              matlab.ui.control.Label
        EjecutarButton                  matlab.ui.control.Button
        SeleccionararchivoButton_5      matlab.ui.control.Button
        SeleccionararchivoButton_4      matlab.ui.control.Button
        UniversidaddeCundinamarcaLabel_2  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        AplicacinLaboratorio4InteligenciaArtificialLabel  matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        ImagenCargada                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)

        PlottedArchitecture = []; 
        OriginalImageData
        AddedKernels = {};
    end

    methods (Access = private)

        function showErrorAlert(app, errorInfo, title)
            if nargin < 3; title = 'Error'; end

            errorMessage = 'Ocurrió un error desconocido.'; 
            stackTraceAvailable = false;
            stackInfo = struct('name', 'N/A', 'line', 0);

            if isa(errorInfo, 'MException') 
                if isprop(errorInfo, 'message') && ~isempty(errorInfo.message) % Verificar propiedad message
                    errorMessage = errorInfo.message;
                else
                    fprintf('WARN: MException no tiene mensaje o está vacío!\n');
                end
                % Verificar stack
                if isprop(errorInfo, 'stack') && ~isempty(errorInfo.stack)
                    stackTraceAvailable = true;
                    stackInfo = errorInfo.stack(1);
                end
            elseif isstruct(errorInfo) && isfield(errorInfo, 'message') % SI ES EL STRUCT MANUAL
                if ~isempty(errorInfo.message)
                    errorMessage = errorInfo.message;
                end
                % No hay stack para structs manuales
            else % Otro tipo, intentar convertir
                try errorMessage = char(errorInfo); catch; end
            end

            fprintf('ERROR DETECTADO (%s): %s\n', title, errorMessage); % Imprimir error real
            if stackTraceAvailable
                try fprintf('  en %s (línea %d)\n', stackInfo.name, stackInfo.line); catch; fprintf('  (Stack info error)\n'); end
            else
                fprintf('  (Sin stack disponible)\n');
            end

            % Actualizar etiquetas de estado
            app.setStatus(app.MensajesdeestadoLabel, ['Error: ' errorMessage]); % Actualiza MensajesdeestadoLabel
            app.setStatus(app.Label_5, ['Error: ' errorMessage]); % Actualiza Label_5 (puede ser redundante, pero seguro)
            try % Mostrar uialert con el mensaje correcto
                if isvalid(app.UIFigure); uialert(app.UIFigure, errorMessage, title, 'Icon','error', 'Modal', true); else; errordlg(errorMessage, title); end
            catch; errordlg(errorMessage, title); end

            % Actualizar etiquetas de estado
            try; if isprop(app, 'MensajesdeestadoLabel') && isvalid(app.MensajesdeestadoLabel); app.setStatus(app.MensajesdeestadoLabel, ['Error: ' errorMessage]); end; catch; end
            try; if isprop(app, 'Label_5') && isvalid(app.Label_5); app.setStatus(app.Label_5, ['Error: ' errorMessage]); end; catch; end

        end

        % --- Helper: Actualizar Status Label --- %%% ASEGÚRATE DE TENER ESTA TAMBIÉN %%%
        function setStatus(app, labelHandle, message)
            % labelHandle aquí DEBE ser el objeto componente real (e.g., app.MensajesdeestadoLabel)
            if isgraphics(labelHandle) && isvalid(labelHandle)
                labelHandle.Text = message;
            else
                disp(['Status (Label no válido): ', message]);
            end
            drawnow('limitrate');
        end

        % --- Helper: Mostrar Alertas de Error ---
        function showError(app, message, title)
            if nargin < 3; title = 'Error'; end
            uialert(app.UIFigure, message, title, 'Icon', 'error');
            app.MensajesdeestadoLabel.Text = ['Error: ' message]; % Mostrar en etiqueta de estado
            drawnow; % Asegurar que se muestre
        end

        % --- Helper: Mostrar Mensajes de Estado ---
        function showStatus(app, message, tab) % tab es 'entrenamiento' o 'aplicacion'
            if strcmpi(tab, 'entrenamiento')
                labelHandle = app.MensajesdeestadoLabel; %%% USA MensajesdeestadoLabel
            elseif strcmpi(tab, 'aplicacion')
                labelHandle = app.Label_5; %%% USA Label_5
            else
                disp(message); return; % Fallback
            end

            if isgraphics(labelHandle) && isvalid(labelHandle)
                labelHandle.Text = message;
            else
                disp(['Status (Label no válido): ', message]);
            end
            drawnow('limitrate');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Limpiar ejes al inicio
            cla(app.VerArquitectura); axis(app.VerArquitectura, 'off'); title(app.VerArquitectura, 'Arquitectura');
            cla(app.UIAxes2); axis(app.UIAxes2, 'off'); title(app.UIAxes2, 'Error vs Épocas');
            cla(app.UIAxes3); axis(app.UIAxes3, 'off'); title(app.UIAxes3, 'Norma Pesos vs Épocas');
            cla(app.ImagenCargada); axis(app.ImagenCargada, 'off'); title(app.ImagenCargada, 'Imagen Aplicación');
            cla(app.UIImagenOriginal); axis(app.UIImagenOriginal, 'off'); title(app.UIImagenOriginal, 'Imagen Original'); % Inicializar UIImagenOriginal
            cla(app.UIImagenTratada); axis(app.UIImagenTratada, 'off'); title(app.UIImagenTratada, 'Imagen Tratada');   % Inicializar UIImagenTratada

            % --- Cargar Configuración usando el Método Estático ---
            configFilePath = fullfile(fileparts(mfilename('fullpath')), 'config.txt');
            config = BP_neuronal_network.leerConfig(configFilePath); % Llamada al método estático

            % --- Establecer Valores en UI (desde config o defaults) ---
            % Usar isfield para comprobar si la clave se leyó correctamente
            if isfield(config, 'alpha')
                app.AlphaEditField.Value = config.alpha;
            else
                app.AlphaEditField.Value = 0.02; % Default
                app.setStatus(app.MensajesdeestadoLabel, 'Alpha no definido en config.txt, usando default.');
            end

            if isfield(config, 'beta')
                app.BetaEditField.Value = config.beta;
            else
                app.BetaEditField.Value = 0.1;  % Default
            end

            if isfield(config, 'precision')
                app.PrecisinEditField.Value = config.precision;
            else
                app.PrecisinEditField.Value = 0.005; % Default
            end

            defaultArchNum = [];
            if isfield(config, 'arquitectura')
                try
                    app.ArquitecturaEditField.Value = mat2str(config.arquitectura);
                    defaultArchNum = config.arquitectura;
                catch
                    warning('Error al convertir arquitectura leída a string. Usando placeholder.');
                    app.ArquitecturaEditField.Value = '[Error Lectura Config]';
                end
            else
                app.ArquitecturaEditField.Value = '[784 15 8]'; % Default string
                defaultArchNum = [784 15 8]; % Default numérico
            end

            % --- Intentar Plotear Arquitectura Inicial ---
            app.PlottedArchitecture = []; % Resetear arquitectura ploteada
            if ~isempty(defaultArchNum)
                try
                    % Llamar a plotArchitecture directamente (ya no se redibuja si no cambia)
                    app.plotArchitecture(app.VerArquitectura, defaultArchNum);
                    app.PlottedArchitecture = defaultArchNum; % Guardar como ploteada
                    app.showStatus('Arquitectura inicial cargada/por defecto mostrada.', 'entrenamiento');
                catch ME_plot
                    warning('No se pudo plotear la arquitectura inicial');
                    title(app.VerArquitectura, 'Arquitectura (Definir y Entrenar)');
                end
            else
                title(app.VerArquitectura, 'Arquitectura (No definida en config)');
            end
            app.GuardarPesosenEditField.Value = 'pesos_entrenados.txt';
            app.PesosEditField.Value = 'pesos_entrenados.txt';

            % Mostrar imagen por defecto en pestaña Aplicación
            try
                defaultImagePathApp = fullfile(fileparts(mfilename('fullpath')), 'VOCALES_IMAGE.png');
                if isfile(defaultImagePathApp)
                    imgDataApp = imread(defaultImagePathApp);
                    imshow(imgDataApp, 'Parent', app.ImagenCargada);
                    axis(app.ImagenCargada, 'image'); axis(app.ImagenCargada, 'off');
                    title(app.ImagenCargada, 'Imagen de Referencia');
                else
                    title(app.ImagenCargada, 'Imagen Defecto No Encontrada');
                end
            catch % Ignorar errores de imagen por defecto
            end
            app.showStatus('Listo.', 'entrenamiento');
            app.showStatus('Listo.', 'aplicacion');

            % Mostrar imagen por defecto en pestaña de tratamiento (UIImagenOriginal y UIImagenTratada)
            defaultImagePathTratamiento = 'Isabella_default.jpeg'; % Nombre de tu imagen por defecto para tratamiento

            try
                defaultImagePathTratamientoFull = fullfile(fileparts(mfilename('fullpath')), defaultImagePathTratamiento);
                if isfile(defaultImagePathTratamientoFull)
                    imgDataTratamiento = imread(defaultImagePathTratamientoFull);
                    imshow(imgDataTratamiento, 'Parent', app.UIImagenOriginal);
                    axis(app.UIImagenOriginal, 'image'); axis(app.UIImagenOriginal, 'off');
                    title(app.UIImagenOriginal, 'Imagen Original'); % Puedes cambiar el título si lo deseas

                    % Replicar la imagen por defecto en UIImagenTratada
                    imshow(imgDataTratamiento, 'Parent', app.UIImagenTratada);
                    axis(app.UIImagenTratada, 'image'); axis(app.UIImagenTratada, 'off');
                    title(app.UIImagenTratada, 'Imagen Tratada');

                else
                    title(app.UIImagenOriginal, 'Imagen Defecto No Encontrada');
                    title(app.UIImagenTratada, 'Imagen Defecto No Encontrada');
                end
            catch ME_tratamiento
                warning('Error al cargar la imagen por defecto para Tratamiento:',' %s', ME_tratamiento.message);
                title(app.UIImagenOriginal, 'Error Imagen Defecto');
                title(app.UIImagenTratada, 'Error Imagen Defecto');
            end

        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)
            mode = app.CasoaEntrenarDropDown.Value;
            app.XTrainData = []; app.YTrainTargets = [];
            app.InputFolderTrain = ''; app.InputFileTrain = '';
            app.EntradasEditField.Value = '';
            app.setStatus(app.MensajesdeestadoLabel, sprintf('Seleccionando entrada para modo %s...', mode)); %%% CORREGIDO AQUÍ (Línea ~795)

            try
                if strcmpi(mode, 'Imagenes')
                    folderName = uigetdir('', 'Selecciona la CARPETA...');
                    if isequal(folderName, 0)
                        app.setStatus(app.MensajesdeestadoLabel, 'Selección carpeta cancelada.'); %%% CORREGIDO
                        return;
                    else
                        app.EntradasEditField.Value = folderName;
                        app.InputFolderTrain = folderName;
                        app.InputFileTrain = '';
                        app.setStatus(app.MensajesdeestadoLabel, 'Carpeta seleccionada. Procesando...'); %%% CORREGIDO
                        app.processImageFolder();
                        % setStatus se llama dentro de processImageFolder, asegúrate que use app.MensajesdeestadoLabel
                    end

                elseif strcmpi(mode, 'Matriz')
                    [fileName, pathName] = uigetfile({'*.txt','Archivos TXT'}, 'Selecciona archivo TXT...');
                    if isequal(fileName, 0)
                        app.setStatus(app.MensajesdeestadoLabel, 'Selección archivo cancelada.'); %%% CORREGIDO
                        return;
                    else
                        fullPath = fullfile(pathName, fileName);
                        app.EntradasEditField.Value = fullPath;
                        app.InputFileTrain = fullPath;
                        app.InputFolderTrain = '';
                        app.setStatus(app.MensajesdeestadoLabel, 'Archivo seleccionado. Cargando vectores...'); %%% CORREGIDO
                        app.loadMatrixInputFile(fullPath); % Asume que este helper existe y usa app.MensajesdeestadoLabel para status
                        app.setStatus(app.MensajesdeestadoLabel, [app.MensajesdeestadoLabel.Text, ' ¡Cargue Salidas!']); %%% CORREGIDO
                    end
                else
                    app.showErrorAlert(struct('message',sprintf('Modo "%s" no reconocido.', mode)), 'Error Modo'); % showErrorAlert ya usa MensajesdeestadoLabel
                end
            catch ME
                app.showErrorAlert(ME, sprintf('Error Procesando Entrada (%s)', mode)); % showErrorAlert ya usa MensajesdeestadoLabel
                app.XTrainData = []; app.YTrainTargets = [];
                app.EntradasEditField.Value = ''; app.InputFolderTrain = ''; app.InputFileTrain = '';
            end

        end

        % Button pushed function: SeleccionararchivoButton_2
        function SeleccionararchivoButton_2Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con las salidas deseadas';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
            if isequal(fileName, 0)
                app.showStatus('Selección cancelada.', 'entrenamiento');
                app.SalidasdeseadasEditField.Value = '';
                app.TargetFileTrain = '';
            else
                fullPath = fullfile(pathName, fileName);
                app.SalidasdeseadasEditField.Value = fullPath;
                app.TargetFileTrain = fullPath;
                app.showStatus(['Archivo de salidas deseadas: ' fullPath], 'entrenamiento');
            end
        end

        % Button pushed function: SeleccionararchivoButton_3
        function SeleccionararchivoButton_3Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con los pesos iniciales (Opcional)';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
            if isequal(fileName, 0)
                app.showStatus('Selección de pesos iniciales cancelada.', 'entrenamiento');
                app.PesosInicialesEditField.Value = '';
                app.InitialWeightsFileTrain = '';
            else
                fullPath = fullfile(pathName, fileName);
                app.PesosInicialesEditField.Value = fullPath;
                app.InitialWeightsFileTrain = fullPath;
                app.showStatus(['Archivo de pesos iniciales: ' fullPath], 'entrenamiento');
            end
        end

        % Button pushed function: SeleccionararchivoButton_5
        function SeleccionararchivoButton_5Pushed(app, event)
            mode = app.CasoaEstudiarrDropDown.Value; % Usar el DropDown de la pestaña Aplicación
            if strcmpi(mode, 'Imagenes')
                filter = {'*.png;*.jpg;*.jpeg', 'Archivos de Imagen (*.png, *.jpg, *.jpeg)'};
                dialogTitle = 'Selecciona la IMAGEN de entrada';
            elseif strcmpi(mode, 'Matriz')
                filter = {'*.txt', 'Archivo de Texto (*.txt)'};
                dialogTitle = 'Selecciona el archivo .TXT con el vector de entrada';
            else
                return; % Modo no reconocido
            end

            [fileName, pathName] = uigetfile(filter, dialogTitle);
            if isequal(fileName, 0)
                app.showStatus('Selección de entrada cancelada.', 'aplicacion');
                app.EntradaEditField.Value = '';
                app.InputFileApp = '';
                cla(app.ImagenCargada); axis(app.ImagenCargada,'off'); title(app.ImagenCargada, 'Imagen/Entrada'); % Limpiar visualización
            else
                fullPath = fullfile(pathName, fileName);
                app.EntradaEditField.Value = fullPath;
                app.InputFileApp = fullPath;
                app.showStatus(['Archivo de entrada listo: ' fullPath], 'aplicacion');

                % --- Mostrar la imagen/matriz cargada ---
                try
                    if strcmpi(mode, 'Imagenes')
                        imgData = imread(fullPath);
                        imshow(imgData,'Parent',app.ImagenCargada);
                        title(app.ImagenCargada, fileName);
                        % ← Aquí guardo el vector preprocesado
                        app.AppInputVector = app.preprocessImage(fullPath);
                    elseif strcmpi(mode, 'Matriz')
                        lines = readlines(fullPath);
                        lines = lines(strlength(lines)>0 & ~startsWith(lines,'%'));
                        vectorStr   = lines{1};
                        vectorData  = double(vectorStr'=='1');
                        app.displayVectorAsMatrix(app.ImagenCargada, vectorData);
                        % ← Y aquí
                        app.AppInputVector = vectorData;
                        app.AppMatrixVector = vectorData;  % opcional, si lo usas separado
                    end
                catch ME_disp
                    app.showError(['Error mostrando entrada: ' ME_disp.message], 'aplicacion');
                    cla(app.ImagenCargada); axis(app.ImagenCargada,'off'); title(app.ImagenCargada, 'Error al Visualizar');
                end
            end
        end

        % Button pushed function: SeleccionararchivoButton_4
        function SeleccionararchivoButton_4Pushed(app, event)
            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Selecciona el archivo .TXT con los pesos entrenados';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
            if isequal(fileName, 0)
                app.showStatus('Selección de pesos cancelada.', 'aplicacion');
                % No limpiar campo, podría querer usar el default
            else
                fullPath = fullfile(pathName, fileName);
                app.PesosEditField.Value = fullPath; % Actualizar campo UI
                app.WeightsFileApp = fullPath;       % Actualizar propiedad
                app.showStatus(['Archivo de pesos seleccionado: ' fullPath], 'aplicacion');
                app.LoadedAppNetwork = BP_neuronal_network();
            end
        end

        % Button pushed function: SeleccionarrutaButton
        function SeleccionarRutaButtonPushed(app, event)
            defaultName = app.GuardarPesosenEditField.Value;
            if isempty(defaultName); defaultName = 'pesos_entrenados.txt'; end

            filter = {'*.txt', 'Archivos de Texto (*.txt)'};
            dialogTitle = 'Guardar pesos entrenados como...';
            [fileName, pathName] = uiputfile(filter, dialogTitle, defaultName);

            if isequal(fileName, 0)
                app.showStatus('Guardado de pesos cancelado.', 'entrenamiento');
                % No limpiar el campo, mantener el valor anterior o por defecto
            else
                fullPath = fullfile(pathName, fileName);
                app.GuardarPesosenEditField.Value = fullPath;
                app.WeightsOutputFileTrain = fullPath;
                app.showStatus(['Archivo para guardar pesos: ' fullPath], 'entrenamiento');
            end
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            app.showStatus('Solicitud de detención recibida...', 'entrenamiento');
            app.StopTrainingRequested = true;
            % Opcional: Deshabilitar el botón detener para evitar clics múltiples
            app.DetenerButton.Enable = 'off';
            drawnow; % Asegurar que el mensaje se muestre
        end

        % Button pushed function: Seleccionarimagenkernel
        function SeleccionarimagenkernelButtonPushed(app, event)
            % filter para seleccionar archivos de imagen
            filter = {'*.png;*.jpg;*.jpeg;*.bmp;*.gif', 'Archivos de Imagen (*.png, *.jpg, *.jpeg, *.bmp, *.gif)'};
            dialogTitle = 'Seleccionar un archivo de imagen';
            [fileName, pathName] = uigetfile(filter, dialogTitle);
            axis(app.UIImagenOriginal, 'off'); % Desactivar los ejes

            if isequal(fileName, 0)
                % El usuario canceló la selección
                app.showStatus('Selección de archivo cancelada.', 'tratamiento'); % Asumiendo que tienes una función showStatus
                app.Rutaimagenkernel.Value = ''; % Limpiar el EditField de la ruta (si lo tienes)
            else
                % Se seleccionó un archivo
                fullPath = fullfile(pathName, fileName);
                app.Rutaimagenkernel.Value = fullPath; %


                % --- Leer y mostrar la imagen en el UIAxes ---
                try
                    imagenOriginal = imread(fullPath);
                    app.OriginalImageData = imagenOriginal; % Guardar la imagen original (importante para el botón Deshacer)
                    imshow(imagenOriginal, 'Parent', app.UIImagenTratada); % Mostrar la imagen original en UIImagenTratada

                    imshow(imagenOriginal, 'Parent', app.UIImagenOriginal);
                catch ME
                    app.showErrorAlert( ME, 'Error de carga');
                    cla(app.UIImagenTratada); % Limpiar el axes en caso de error
                    title(app.UIImagenTratada, 'Error al cargar');

                    cla(app.UIImagenOriginal);
                    title(app.UIImagenOriginal, 'Error al cargar');
                end
            end
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)
            % Verificar si hay una imagen original guardada
            if isprop(app, 'OriginalImageData') && ~isempty(app.OriginalImageData)
                % Restaurar la imagen original en UIImagenOriginal
                imshow(app.OriginalImageData, 'Parent', app.UIImagenOriginal);
                axis(app.UIImagenOriginal, 'image');
                axis(app.UIImagenOriginal, 'off');
                title(app.UIImagenOriginal, 'Imagen Original');

                % Limpiar UIImagenTratada y su título
                cla(app.UIImagenTratada);
                axis(app.UIImagenTratada, 'off');
                title(app.UIImagenTratada, 'Imagen Tratada');

                % Resetear las selecciones de los desplegables
                if isprop(app, 'Kernel3x3DropDown')
                    app.Kernel3x3DropDown.Value = 'Ninguno';
                end
                if isprop(app, 'Kernel5x5DropDown')
                    app.Kernel5x5DropDown.Value = 'Ninguno';
                end
                if isprop(app, 'FiltrosdeColorDropDown')
                    app.FiltrosdeColorDropDown.Value = 'Ninguno';
                end
            else
                uialert(app.UIFigure, 'No hay una imagen original para deshacer.', 'Advertencia');
            end
        end

        % Value changed function: Kernel3x3DropDown
        function Kernel3x3DropDownValueChanged(app, event)
        % Función de callback para los desplegables de kernel y filtro de color
        hImageOriginal = findobj(app.UIImagenOriginal, 'Type', 'image');
        if ~isempty(hImageOriginal)
            imagen_original = hImageOriginal.CData;
        else
            uialert(app.UIFigure, 'No se encontró ninguna imagen cargada.', 'Advertencia');
            return; % Salir de la función si no hay imagen
        end

        kernel_seleccionado = '';
        filtro_color_seleccionado = '';

        % Verificar si se seleccionó "Ninguno" en algún desplegable
        if strcmp(app.Kernel3x3DropDown.Value, 'Ninguno') && strcmp(app.Kernel5x5DropDown.Value, 'Ninguno') && strcmp(app.FiltrosdeColorDropDown.Value, 'Ninguno')
            % Mostrar la imagen original en UIImagenTratada
            if isprop(app, 'OriginalImageData') && ~isempty(app.OriginalImageData)
                imshow(app.OriginalImageData, 'Parent', app.UIImagenTratada);
                axis(app.UIImagenTratada, 'image');
                axis(app.UIImagenTratada, 'off');
                title(app.UIImagenTratada, 'Imagen Tratada');
            else
                cla(app.UIImagenTratada);
                axis(app.UIImagenTratada, 'off');
                title(app.UIImagenTratada, 'Imagen Tratada (Sin filtro)');
            end
            return; % Salir de la función si no se selecciona ningún filtro
        end

        % Determinar qué kernel está seleccionado (prioridad a 3x3, luego 5x5)
        if isprop(app, 'Kernel3x3DropDown') && ~strcmp(app.Kernel3x3DropDown.Value, 'Ninguno')
            kernel_seleccionado = app.Kernel3x3DropDown.Value;
            kernel_seleccionado = strrep(kernel_seleccionado, ' ', '_');
        elseif isprop(app, 'Kernel5x5DropDown') && ~strcmp(app.Kernel5x5DropDown.Value, 'Ninguno')
            kernel_seleccionado = app.Kernel5x5DropDown.Value;
            kernel_seleccionado = strrep(kernel_seleccionado, ' ', '_');
        end

        if isprop(app, 'FiltrosdeColorDropDown') && ~strcmp(app.FiltrosdeColorDropDown.Value, 'Ninguno')
            filtro_color_seleccionado = app.FiltrosdeColorDropDown.Value;
        end

        if ~isempty(imagen_original) && (~isempty(kernel_seleccionado) || ~isempty(filtro_color_seleccionado))
            imagen_tratada = Aplicar_filtros(imagen_original, kernel_seleccionado, filtro_color_seleccionado);
            imshow(imagen_tratada, 'Parent', app.UIImagenTratada);
            axis(app.UIImagenTratada, 'image');
            axis(app.UIImagenTratada, 'off');
            title(app.UIImagenTratada, 'Imagen Tratada');
        elseif isempty(imagen_original)
            uialert(app.UIFigure, 'Por favor, cargue una imagen primero.', 'Advertencia');
        end
        end

        % Value changed function: Kernel5x5DropDown
        function Kernel5x5DropDownValueChanged(app, event)
            Kernel3x3DropDownValueChanged(app, event);
        end

        % Value changed function: FiltrosdeColorDropDown
        function FiltrosdeColorDropDownValueChanged(app, event)
            Kernel3x3DropDownValueChanged(app, event);
        end

        % Callback function: not associated with a component
        function AadirKernelButtonPushed(app, event)

        end

        % Button pushed function: AgregarKernelButton
        function AgregarKernelButtonPushed(app, event)
        kernel_seleccionado = '';
        filtro_color_seleccionado = '';
    
        % Obtener el kernel seleccionado
        if isprop(app, 'Kernel3x3DropDown') && ~strcmp(app.Kernel3x3DropDown.Value, 'Ninguno')
            kernel_seleccionado = app.Kernel3x3DropDown.Value;
            kernel_seleccionado = strrep(kernel_seleccionado, ' ', '_');
        elseif isprop(app, 'Kernel5x5DropDown') && ~strcmp(app.Kernel5x5DropDown.Value, 'Ninguno')
            kernel_seleccionado = app.Kernel5x5DropDown.Value;
            kernel_seleccionado = strrep(kernel_seleccionado, ' ', '_');
        end
    
        % Obtener el filtro de color seleccionado
        if isprop(app, 'FiltrosdeColorDropDown') && ~strcmp(app.FiltrosdeColorDropDown.Value, 'Ninguno')
            filtro_color_seleccionado = app.FiltrosdeColorDropDown.Value;
        end
    
        % Añadir el kernel o filtro seleccionado al vector
        if ~isempty(kernel_seleccionado)
            app.AddedKernels{end+1} = kernel_seleccionado;
        elseif ~isempty(filtro_color_seleccionado)
            app.AddedKernels{end+1} = filtro_color_seleccionado;
        else
            uialert(app.UIFigure, 'Por favor, seleccione un kernel o filtro antes de añadir.', 'Advertencia');
            return;
        end
    
        % Actualizar el ListBox
        app.AddedKernelsListBox.Items = app.AddedKernels;
    
        % Mostrar un mensaje de confirmación
        disp([app.AddedKernels{end}, ' añadido a la lista.']);
        end

        % Button pushed function: MostrarKernelListaButton
        function MostrarKernelListaButtonPushed(app, event)
        if isempty(app.AddedKernels)
            uialert(app.UIFigure, 'No se han añadido kernels o filtros.', 'Información');
        else
            % Crear una lista formateada para mostrar en un cuadro de diálogo o en la consola
            listaKernels = strjoin(app.AddedKernels, '\n');
            
            % Mostrar la lista en un cuadro de diálogo
            uialert(app.UIFigure, listaKernels, 'Kernels/Filtros Añadidos');
    
            disp('Kernels/Filtros Añadidos:');
            disp(listaKernels);
        end
        end

        % Button pushed function: EliminarKernelButton
        function EliminarKernelButtonPushed(app, event)
       selectedItem = app.AddedKernelsListBox.Value;

    if isempty(selectedItem)
        uialert(app.UIFigure, 'Por favor, seleccione un kernel o filtro para borrar de la lista.', 'Advertencia');
        return;
    end

    % Encontrar el índice del elemento seleccionado
    indexToRemove = find(strcmp(app.AddedKernels, selectedItem));

    if ~isempty(indexToRemove)
        app.AddedKernels(indexToRemove) = [];
        % Actualizar el ListBox
        app.AddedKernelsListBox.Items = app.AddedKernels;
        % Mostrar un mensaje de confirmación
        disp(['Kernel/filtro "', selectedItem, '" borrado de la lista.']);
    else
        warning('El elemento seleccionado no se encontró en la lista interna.');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TratamientoTab = uitab(app.TabGroup);
            app.TratamientoTab.Title = 'Tratamiento';
            app.UIImagenOriginal = uiaxes(app.TratamientoTab);
            title(app.UIImagenOriginal, 'Title')
            app.UIImagenTratada = uiaxes(app.TratamientoTab);
            title(app.UIImagenTratada, 'Title')
            app.EntrenamientoLaboratorio4InteligenciaArtificialLabel_2 = uilabel(app.TratamientoTab);
            app.EntrenamientoLaboratorio4InteligenciaArtificialLabel_2.Text = 'Entrenamiento Laboratorio #4 Inteligencia Artificial';
            app.Image_3 = uiimage(app.TratamientoTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label_6 = uilabel(app.TratamientoTab);
            app.Label_6.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel_3 = uilabel(app.TratamientoTab);
            app.UniversidaddeCundinamarcaLabel_3.Text = 'Universidad de Cundinamarca';
            app.Kernel3x3DropDownLabel = uilabel(app.TratamientoTab);
            app.Kernel3x3DropDownLabel.Text = 'Kernel 3x3';
            app.Kernel3x3DropDown = uidropdown(app.TratamientoTab);
            app.Kernel3x3DropDown.Items = {'Enfoque Sharpen', 'Deteccion de bordes Laplaciano', 'Deteccion de Bordes Fuerte', 'Desenfoque Box Blur', 'Repujado Emboss 1', 'Repujado Emboss 2', 'Prewitt X', 'Prewitt Y', 'Sobel X', 'Sobel Y', 'Realce Laplaciano', 'Ninguno'};
            app.Kernel3x3DropDown.ValueChangedFcn = createCallbackFcn(app, @Kernel3x3DropDownValueChanged, true);
            app.Kernel3x3DropDown.Value = 'Enfoque Sharpen';
            app.DeshacerButton = uibutton(app.TratamientoTab, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Text = 'Deshacer ';
            app.Seleccionarimagenkernel = uibutton(app.TratamientoTab, 'push');
            app.Seleccionarimagenkernel.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarimagenkernelButtonPushed, true);
            app.Seleccionarimagenkernel.Text = 'Seleccionar archivo';
            app.EntradasEditField_2Label = uilabel(app.TratamientoTab);
            app.EntradasEditField_2Label.Text = 'Entradas';
            app.Rutaimagenkernel = uieditfield(app.TratamientoTab, 'text');
            app.Rutaimagenkernel.Placeholder = 'C:/Users/folder';
            app.Kernel5x5DropDownLabel = uilabel(app.TratamientoTab);
            app.Kernel5x5DropDownLabel.Text = 'Kernel 5x5';
            app.Kernel5x5DropDown = uidropdown(app.TratamientoTab);
            app.Kernel5x5DropDown.Items = {'Desenfoque Gaussiano', 'Desenfoque Medio', 'Deteccion Bordes Laplaciano', 'Realce Sharpen 5x5', 'Relieve Emboss 5x5', 'Prewitt X 5x5', 'Prewitt Y 5x5', 'Ninguno'};
            app.Kernel5x5DropDown.ValueChangedFcn = createCallbackFcn(app, @Kernel5x5DropDownValueChanged, true);
            app.Kernel5x5DropDown.Value = 'Desenfoque Gaussiano';
            app.FiltrosdeColorDropDownLabel = uilabel(app.TratamientoTab);
            app.FiltrosdeColorDropDownLabel.Text = 'Filtros de Color';
            app.FiltrosdeColorDropDown = uidropdown(app.TratamientoTab);
            app.FiltrosdeColorDropDown.Items = {'Escala de Grises', 'Sepia', 'Invertir', 'Blanco y Negro', 'Cálido', 'Frío', 'Ajustar Saturación', 'Disminuir Saturación', 'Ninguno'};
            app.FiltrosdeColorDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltrosdeColorDropDownValueChanged, true);
            app.FiltrosdeColorDropDown.Value = 'Sepia';
            app.FiltrosaAplicarLabel = uilabel(app.TratamientoTab);
            app.FiltrosaAplicarLabel.Text = 'Filtros a Aplicar';
            app.AgregarKernelButton = uibutton(app.TratamientoTab, 'push');
            app.AgregarKernelButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarKernelButtonPushed, true);
            app.AgregarKernelButton.Text = 'Añadir Kernel';
            app.MostrarKernelListaButton = uibutton(app.TratamientoTab, 'push');
            app.MostrarKernelListaButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarKernelListaButtonPushed, true);
            app.MostrarKernelListaButton.Text = 'Mostrar Kernels Añadidos';
            app.EliminarKernelButton = uibutton(app.TratamientoTab, 'push');
            app.EliminarKernelButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarKernelButtonPushed, true);
            app.EliminarKernelButton.Text = 'Eliminar Kernel';
            app.DatosparaentrenamientoLabel = uilabel(app.TratamientoTab);
            app.DatosparaentrenamientoLabel.Text = 'Datos para entrenamiento';
            app.KernelsAadidosLabel = uilabel(app.TratamientoTab);
            app.KernelsAadidosLabel.Text = 'Kernels Añadidos';
            app.AddedKernelsListBox = uilistbox(app.TratamientoTab);
            app.AddedKernelsListBox.Items = {''};
            app.AddedKernelsListBox.Value = '';
            app.EntrenamientoTab = uitab(app.TabGroup);
            app.EntrenamientoTab.Title = 'Entrenamiento';
            app.UIAxes3 = uiaxes(app.EntrenamientoTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.EntrenamientoTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.VerArquitectura = uiaxes(app.EntrenamientoTab);
            title(app.VerArquitectura, 'Title')
            xlabel(app.VerArquitectura, 'X')
            ylabel(app.VerArquitectura, 'Y')
            zlabel(app.VerArquitectura, 'Z')
            app.EntrenamientoLaboratorio4InteligenciaArtificialLabel = uilabel(app.EntrenamientoTab);
            app.EntrenamientoLaboratorio4InteligenciaArtificialLabel.Text = 'Entrenamiento Laboratorio #4 Inteligencia Artificial';
            app.Image = uiimage(app.EntrenamientoTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label = uilabel(app.EntrenamientoTab);
            app.Label.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel = uilabel(app.EntrenamientoTab);
            app.UniversidaddeCundinamarcaLabel.Text = 'Universidad de Cundinamarca';
            app.DatosdeentrenamientoLabel = uilabel(app.EntrenamientoTab);
            app.DatosdeentrenamientoLabel.Text = 'Datos de entrenamiento';
            app.CargadearchivosporfavorseleccioneunarchivoLabel = uilabel(app.EntrenamientoTab);
            app.CargadearchivosporfavorseleccioneunarchivoLabel.Text = 'Carga de archivos, por favor seleccione un archivo';
            app.SeleccionararchivoButton = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_2 = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton_2.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_2Pushed, true);
            app.SeleccionararchivoButton_2.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_3 = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionararchivoButton_3.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_3Pushed, true);
            app.SeleccionararchivoButton_3.Text = 'Seleccionar archivo';
            app.EntrenarButton = uibutton(app.EntrenamientoTab, 'push');
            app.EntrenarButton.Text = 'Entrenar';
            app.ArquitecturaLabel = uilabel(app.EntrenamientoTab);
            app.ArquitecturaLabel.Text = 'Arquitectura';
            app.PesosvspocasLabel = uilabel(app.EntrenamientoTab);
            app.PesosvspocasLabel.Text = 'Pesos vs Épocas';
            app.ErrorvspocasLabel = uilabel(app.EntrenamientoTab);
            app.ErrorvspocasLabel.Text = 'Error vs Épocas';
            app.ArquitecturaEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ArquitecturaEditFieldLabel.Text = 'Arquitectura';
            app.ArquitecturaEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.ArquitecturaEditField.Placeholder = '5 4 2';
            app.PesosInicialesEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.PesosInicialesEditField.Placeholder = 'C:/Users/folder';
            app.PesosInicialesEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.PesosInicialesEditFieldLabel.Text = {'Pesos'; 'Iniciales'};
            app.GuardarPesosenEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.GuardarPesosenEditFieldLabel.Text = {'Guardar '; 'Pesos en'};
            app.GuardarPesosenEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.GuardarPesosenEditField.Value = 'pesos_entrenados_vocales.txt';
            app.MensajesdeestadoLabel = uilabel(app.EntrenamientoTab);
            app.MensajesdeestadoLabel.Text = '';
            app.pocasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.pocasEditFieldLabel.Text = 'Épocas';
            app.pocasEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.pocasEditField.Editable = 'off';
            app.ErrorFinalEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ErrorFinalEditFieldLabel.Text = 'Error Final';
            app.ErrorFinalEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorFinalEditField.Editable = 'off';
            app.EntradasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.EntradasEditFieldLabel.Text = 'Entradas';
            app.EntradasEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.EntradasEditField.Placeholder = 'C:/Users/folder';
            app.SalidasdeseadasEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.SalidasdeseadasEditFieldLabel.Text = {'Salidas'; 'deseadas'};
            app.SalidasdeseadasEditField = uieditfield(app.EntrenamientoTab, 'text');
            app.SalidasdeseadasEditField.Placeholder = 'C:/Users/folder';
            app.AlphaEditField_2Label = uilabel(app.EntrenamientoTab);
            app.AlphaEditField_2Label.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.AlphaEditField.Value = 0.01;
            app.BetaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.BetaEditField.Value = 0.01;
            app.BetaEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.PrecisinEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.PrecisinEditFieldLabel.Text = 'Precisión';
            app.PrecisinEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.PrecisinEditField.Value = 0.01;
            app.SeleccionarrutaButton = uibutton(app.EntrenamientoTab, 'push');
            app.SeleccionarrutaButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarRutaButtonPushed, true);
            app.SeleccionarrutaButton.Text = 'Seleccionar ruta';
            app.PrecisionClaficicacinLabel = uilabel(app.EntrenamientoTab);
            app.PrecisionClaficicacinLabel.Text = {'Precision '; 'Clasificación %'};
            app.ErrorGeneralEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorGeneralEditField.Editable = 'off';
            app.DetenerButton = uibutton(app.EntrenamientoTab, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.pocaactualEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.pocaactualEditFieldLabel.Text = 'Época actual';
            app.EpocaActualEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.EpocaActualEditField.Editable = 'off';
            app.ErrorActualEditFieldLabel = uilabel(app.EntrenamientoTab);
            app.ErrorActualEditFieldLabel.Text = 'Error Actual';
            app.ErrorActualEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ErrorActualEditField.Editable = 'off';
            app.AlphaEditField_2Label_2 = uilabel(app.EntrenamientoTab);
            app.AlphaEditField_2Label_2.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.EntrenamientoTab, 'numeric');
            app.ThetaEditField.Value = 0.01;
            app.AplicacinTab = uitab(app.TabGroup);
            app.AplicacinTab.Title = 'Aplicación';
            app.ImagenCargada = uiaxes(app.AplicacinTab);
            title(app.ImagenCargada, 'Title')
            zlabel(app.ImagenCargada, 'Z')
            app.UIAxes4 = uiaxes(app.AplicacinTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.AplicacinLaboratorio4InteligenciaArtificialLabel = uilabel(app.AplicacinTab);
            app.AplicacinLaboratorio4InteligenciaArtificialLabel.Text = 'Aplicación Laboratorio #4 Inteligencia Artificial';
            app.Image_2 = uiimage(app.AplicacinTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'IMAGOTIPO VERTICAL COLOR.png');
            app.Label_2 = uilabel(app.AplicacinTab);
            app.Label_2.Text = {'Presentado por Juan Esteban Fuentes Rojas, Duvan Santiago Matallana Jiménez y'; ' Laura Estefania Latorre Pachon'};
            app.UniversidaddeCundinamarcaLabel_2 = uilabel(app.AplicacinTab);
            app.UniversidaddeCundinamarcaLabel_2.Text = 'Universidad de Cundinamarca';
            app.SeleccionararchivoButton_4 = uibutton(app.AplicacinTab, 'push');
            app.SeleccionararchivoButton_4.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_4Pushed, true);
            app.SeleccionararchivoButton_4.Text = 'Seleccionar archivo';
            app.SeleccionararchivoButton_5 = uibutton(app.AplicacinTab, 'push');
            app.SeleccionararchivoButton_5.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButton_5Pushed, true);
            app.SeleccionararchivoButton_5.Text = 'Seleccionar archivo';
            app.EjecutarButton = uibutton(app.AplicacinTab, 'push');
            app.EjecutarButton.Text = 'Ejecutar';
            app.ImagencargadaLabel = uilabel(app.AplicacinTab);
            app.ImagencargadaLabel.Text = 'Imagen cargada';
            app.Label_5 = uilabel(app.AplicacinTab);
            app.Label_5.Text = '';
            app.EntradaEditFieldLabel = uilabel(app.AplicacinTab);
            app.EntradaEditFieldLabel.Text = 'Entrada';
            app.EntradaEditField = uieditfield(app.AplicacinTab, 'text');
            app.PesosEditFieldLabel = uilabel(app.AplicacinTab);
            app.PesosEditFieldLabel.Text = 'Pesos';
            app.PesosEditField = uieditfield(app.AplicacinTab, 'text');
            app.TipomariposaEditFieldLabel = uilabel(app.AplicacinTab);
            app.TipomariposaEditFieldLabel.Text = 'Tipo mariposa';
            app.TipomariposaEditField = uieditfield(app.AplicacinTab, 'text');
            app.HistogramaRGBLabel = uilabel(app.AplicacinTab);
            app.HistogramaRGBLabel.Text = 'Histograma RGB';
            app.ButtonGroup = uibuttongroup(app.AplicacinTab);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.BrilloButton = uibutton(app.AplicacinTab, 'push');
            app.BrilloButton.Text = 'Brillo';
            app.RGBButton = uibutton(app.AplicacinTab, 'push');
            app.RGBButton.Text = 'RGB';
            app.RedButton = uibutton(app.AplicacinTab, 'push');
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.AplicacinTab, 'push');
            app.GreenButton.Text = 'Green';
            app.BlueButton = uibutton(app.AplicacinTab, 'push');
            app.BlueButton.Text = 'Blue';
            app.DimensionesEditFieldLabel = uilabel(app.AplicacinTab);
            app.DimensionesEditFieldLabel.Text = 'Dimensiones';
            app.DimensionesEditField = uieditfield(app.AplicacinTab, 'text');
            app.TamaoEditFieldLabel = uilabel(app.AplicacinTab);
            app.TamaoEditFieldLabel.Text = 'Tamaño';
            app.TamaoEditField = uieditfield(app.AplicacinTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Mariposas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end